classdef invFilterDesignApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        UIAxes         matlab.ui.control.UIAxes
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes3        matlab.ui.control.UIAxes
        ListBoxLabel   matlab.ui.control.Label
        ListBox        matlab.ui.control.ListBox
        gainKnobLabel  matlab.ui.control.Label
        gainKnob       matlab.ui.control.Knob
        fcKnobLabel    matlab.ui.control.Label
        fcKnob         matlab.ui.control.Knob
        bwKnobLabel    matlab.ui.control.Label
        bwKnob         matlab.ui.control.Knob
        DropDownLabel  matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        Filters % Filters from the algorithm
        SelectedFilter
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Filters = invFilterDesign(app.UIAxes,app.UIAxes2);
            items = cell(1,size(app.Filters,1));
            for i=1:size(app.Filters,1)
                items{i} = ['Szűrő ' num2str(i,'%d')];  
            end
            app.ListBox.Items = items;
            app.DropDown.Items = items;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            for i = 1:size(app.Filters,1)
                if value == app.DropDown.Items{i}
                    %kirajzoljuk a szűrőt
                    app.SelectedFilter = i;
                    configFile = 'config.mat';
                    load(configFile,'START_FREQ','END_FREQ','PLOT_FLINES')
                    xlim(app.UIAxes3,[START_FREQ END_FREQ])
                    fs = 44100;
                    f_interp_plot = logspace(log10(START_FREQ),log10(END_FREQ),PLOT_FLINES);
                    if (app.Filters(i,6) == 0) % parametric filter
                        [He,~] = parametricEQ(app.Filters(i,1),app.Filters(i,2),app.Filters(i,3),fs,f_interp_plot);
                        %H_mic_o_plot = H_mic_o_plot + He; 
                        semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))
                        if app.Filters(i,1) < 0
                            app.gainKnob.Limits = [app.Filters(i,1)*3 app.Filters(i,1)*(-3)];
                        else
                            app.gainKnob.Limits = [app.Filters(i,1)*(-3) app.Filters(i,1)*3];
                        end
                        app.gainKnob.Value = app.Filters(i,1);

                    else % butterworth filter
                        if(app.Filters(i,6) == 1)
                            [b,a] = butter(1,app.Filters(i,2)/(fs/2),'high');
                        else
                            [b,a] = butter(1,app.Filters(i,2)/(fs/2),'low');
                        end
                        [He,~] = freqz(b,a,f_interp_plot,fs);
                        He = 20*log10(abs(He));
                        %H_mic_o_plot = H_mic_o_plot + He; 
                        semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))
                    end
                    app.fcKnob.Limits = [START_FREQ*0.5 END_FREQ*1.5];
                    app.fcKnob.Value = app.Filters(i,2);
                    
                    break;
                end
            end
        end

        % Value changed function: gainKnob
        function gainKnobValueChanged(app, event)
            value = app.gainKnob.Value;
           
        end

        % Value changing function: gainKnob
        function gainKnobValueChanging(app, event)
            changingValue = event.Value;
             fs = 44100;
            configFile = 'config.mat';
            load(configFile,'START_FREQ','END_FREQ','PLOT_FLINES')
            f_interp_plot = logspace(log10(START_FREQ),log10(END_FREQ),PLOT_FLINES);
            i = app.SelectedFilter;
            if (app.Filters(i,6) == 0) % parametric filter
                [He,~] = parametricEQ(changingValue,app.Filters(i,2),app.Filters(i,3),fs,f_interp_plot);
                %H_mic_o_plot = H_mic_o_plot + He; 
                semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))

            else % butterworth filter
                if(app.Filters(i,6) == 1)
                    [b,a] = butter(1,app.Filters(i,2)/(fs/2),'high');
                else
                    [b,a] = butter(1,app.Filters(i,2)/(fs/2),'low');
                end
                [He,~] = freqz(b,a,f_interp_plot,fs);
                He = 20*log10(abs(He));
                %H_mic_o_plot = H_mic_o_plot + He; 
                semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))
            end
        end

        % Value changing function: fcKnob
        function fcKnobValueChanging(app, event)
            changingValue = event.Value;
            fs = 44100;
            configFile = 'config.mat';
            load(configFile,'START_FREQ','END_FREQ','PLOT_FLINES')
            f_interp_plot = logspace(log10(START_FREQ),log10(END_FREQ),PLOT_FLINES);
            i = app.SelectedFilter;
            if (app.Filters(i,6) == 0) % parametric filter
                [He,~] = parametricEQ(app.Filters(i,1),changingValue,app.Filters(i,3),fs,f_interp_plot);
                %H_mic_o_plot = H_mic_o_plot + He; 
                semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))

            else % butterworth filter
                if(app.Filters(i,6) == 1)
                    [b,a] = butter(1,changingValue/(fs/2),'high');
                else
                    [b,a] = butter(1,changingValue/(fs/2),'low');
                end
                [He,~] = freqz(b,a,f_interp_plot,fs);
                He = 20*log10(abs(He));
                %H_mic_o_plot = H_mic_o_plot + He; 
                semilogx(app.UIAxes3,f_interp_plot,He,'DisplayName', string(i))
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Átvitel változása')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Szűrők')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Végleges átvitel')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.gainKnobLabel = uilabel(app.UIFigure);
            app.gainKnobLabel.Text = 'gain';
            app.gainKnob = uiknob(app.UIFigure, 'continuous');
            app.gainKnob.ValueChangedFcn = createCallbackFcn(app, @gainKnobValueChanged, true);
            app.gainKnob.ValueChangingFcn = createCallbackFcn(app, @gainKnobValueChanging, true);
            app.fcKnobLabel = uilabel(app.UIFigure);
            app.fcKnobLabel.Text = 'fc';
            app.fcKnob = uiknob(app.UIFigure, 'continuous');
            app.fcKnob.ValueChangingFcn = createCallbackFcn(app, @fcKnobValueChanging, true);
            app.bwKnobLabel = uilabel(app.UIFigure);
            app.bwKnobLabel.Text = 'bw';
            app.bwKnob = uiknob(app.UIFigure, 'continuous');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = invFilterDesignApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end